classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainMenuUIFigure            matlab.ui.Figure
        STARTGAMEButton             matlab.ui.control.Button
        TutorialButton              matlab.ui.control.Button
        HistoryButton               matlab.ui.control.Button
        RockPaperScissorsgameLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTGAMEButton
        function STARTGAMEButtonPushed(app, event)
            app1
            closereq();
        end

        % Button pushed function: TutorialButton
        function TutorialButtonPushed(app, event)
            app3
        end

        % Button pushed function: HistoryButton
        function HistoryButtonPushed(app, event)
            msgbox('The first known mention of the game was in the book Wuzazu  by the Chinese Ming-dynasty writer Xie Zhaozhi (fl. c. 1600), who wrote that the game dated back to the time of the Chinese Han dynasty (206 BCE – 220 CE). In the book, the game was called shoushiling. Li Rihuas book Note of Liuyanzhai also mentions this game, calling it shoushiling (t. 手勢令; s. 手势令), huozhitou (t. 豁指頭; s. 豁指头), or huaquan (划拳).')            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainMenuUIFigure = uifigure('Visible', 'off');
            app.MainMenuUIFigure.Name = 'Main Menu';
            app.STARTGAMEButton = uibutton(app.MainMenuUIFigure, 'push');
            app.STARTGAMEButton.ButtonPushedFcn = createCallbackFcn(app, @STARTGAMEButtonPushed, true);
            app.STARTGAMEButton.Text = 'START GAME';
            app.TutorialButton = uibutton(app.MainMenuUIFigure, 'push');
            app.TutorialButton.ButtonPushedFcn = createCallbackFcn(app, @TutorialButtonPushed, true);
            app.TutorialButton.Text = {'Tutorial'; ''};
            app.HistoryButton = uibutton(app.MainMenuUIFigure, 'push');
            app.HistoryButton.ButtonPushedFcn = createCallbackFcn(app, @HistoryButtonPushed, true);
            app.HistoryButton.Text = 'History ';
            app.RockPaperScissorsgameLabel = uilabel(app.MainMenuUIFigure);
            app.RockPaperScissorsgameLabel.Text = {'Rock Paper Scissors game'; ''};
            % Show the figure after all components are created
            app.MainMenuUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainMenuUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainMenuUIFigure)
        end
    end
end