classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RockpaperscissorsUIFigure  matlab.ui.Figure
        RockButton                 matlab.ui.control.Button
        ScissorButton              matlab.ui.control.Button
        PaperButton                matlab.ui.control.Button
        StartGameLabel             matlab.ui.control.Label
        backtoMainMenuButton       matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function HowtoplayButtonPushed(app, event)
        image=imread("tut.jpg")
            msgbox({'RULES';'';'Rock:'; '1. rock can beat scissor'; '2. rock can not beat paper'; '3. rock ties with rock';'';'Scissor:'; '1. scissor can beat paper';'2. scissor can not beat rock'; '3. scissor ties with scissor'; '';'Paper:'; '1. Paper can beat Rock'; '2. Paper can not beat Scissor'; '3. Paper ties with Paper'},'tutorial','custom',image)
        end

        % Button pushed function: RockButton
        function RockButtonPushed(app, event)
            play=imread('rock.png');
            subplot(121);
            imshow(play);
            title('Your input is ROCK');
            pause(0.3);
            computerchoice=randi(3);
            
            if computerchoice==1
            play1=imread('rock.png');
            subplot(122);
            imshow(play1);
            title('Computers choice is ROCK');
            msgbox('TIE');
             
    
        elseif computerchoice==2
       
            play1=imread('paper.png');
            subplot(122);
            imshow(play1);
            title('Computers choice is PAPER');
            msgbox('Computer wins');
        
      
    elseif computerchoice==3
       
            play1=imread('scissors.png');
            subplot(122);
            imshow(play1);
            title('Computers choice is SCISSOR');
            msgbox('player wins');
        end
        clear all
        end

        % Button pushed function: ScissorButton
        function ScissorButtonPushed(app, event)
            play2=imread('scissors.png');
            subplot(121);
            imshow(play2);
            title('Computers choice is SCISSOR');
            pause(0.3);
            computerchoice=randi(3);
            if computerchoice==1
            play1=imread('rock.png');
            subplot(122);
            imshow(play1);
            title('Computers choice is ROCK');
            msgbox('Computer wins');
    
            elseif computerchoice==2
       
            play1=imread('paper.png');
            subplot(122);
            imshow(play1);
            title('Computers choice is PAPER');
            msgbox('Player wins');
        
      
            elseif computerchoice==3
            play1=imread('scissors.png');
            subplot(122);
            imshow(play1);
            title('Computers choice is SCISSOR');
            msgbox('TIE');
        end
        clear all
        end

        % Button pushed function: PaperButton
        function PaperButtonPushed(app, event)
            play3=imread('paper.png');
            subplot(121);
            imshow(play3);
            title('Computers choice is PAPER');
            pause(0.3);
            computerchoice=randi(3);
            if computerchoice==1
            play1=imread('rock.png');
            subplot(122);
            imshow(play1);
            title('COMPUTER CHOOSE ROCK');
            msgbox('Player wins');
            
            elseif computerchoice==2
            play1=imread('paper.png');
            subplot(122);
            imshow(play1);
            title('COMPUTER CHOOSE PAPER');
            msgbox('TIE');
        
            elseif computerchoice==3
            play1=imread('scissors.png');
            subplot(122);
            imshow(play1);
            title('COMPUTER CHOOSE SCISSOR');
            msgbox('Computer wins');
        end
        clear all
        end

        % Callback function
        function BacktoMainmenuMenuSelected(app, event)
            Main
            closereq;
        end

        % Button pushed function: backtoMainMenuButton
        function backtoMainMenuButtonPushed(app, event)
            Main
            closereq;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RockpaperscissorsUIFigure = uifigure('Visible', 'off');
            app.RockpaperscissorsUIFigure.Name = 'Rock paper scissors';
            app.RockButton = uibutton(app.RockpaperscissorsUIFigure, 'push');
            app.RockButton.ButtonPushedFcn = createCallbackFcn(app, @RockButtonPushed, true);
            app.RockButton.Text = 'Rock';
            app.ScissorButton = uibutton(app.RockpaperscissorsUIFigure, 'push');
            app.ScissorButton.ButtonPushedFcn = createCallbackFcn(app, @ScissorButtonPushed, true);
            app.ScissorButton.Text = 'Scissor';
            app.PaperButton = uibutton(app.RockpaperscissorsUIFigure, 'push');
            app.PaperButton.ButtonPushedFcn = createCallbackFcn(app, @PaperButtonPushed, true);
            app.PaperButton.Text = 'Paper';
            app.StartGameLabel = uilabel(app.RockpaperscissorsUIFigure);
            app.StartGameLabel.Text = {'Start Game'; ''};
            app.backtoMainMenuButton = uibutton(app.RockpaperscissorsUIFigure, 'push');
            app.backtoMainMenuButton.ButtonPushedFcn = createCallbackFcn(app, @backtoMainMenuButtonPushed, true);
            app.backtoMainMenuButton.Text = {'back to Main Menu'; ''};
            % Show the figure after all components are created
            app.RockpaperscissorsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RockpaperscissorsUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RockpaperscissorsUIFigure)
        end
    end
end